classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotButton                  matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        Tab2                        matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        TypeKnob                    matlab.ui.control.DiscreteKnob
        TypeKnobLabel               matlab.ui.control.Label
        DampnessGauge               matlab.ui.control.SemicircularGauge
        DampnessGaugeLabel          matlab.ui.control.Label
        StifnessKnob                matlab.ui.control.Knob
        StifnessKnobLabel           matlab.ui.control.Label
        DampnessSlider              matlab.ui.control.Slider
        DampnessSliderLabel         matlab.ui.control.Label
        MassSlider                  matlab.ui.control.Slider
        MassSliderLabel             matlab.ui.control.Label
        VelocityEditField           matlab.ui.control.NumericEditField
        VelocityLabel               matlab.ui.control.Label
        DisplacementEditField       matlab.ui.control.NumericEditField
        DisplacementEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        t
        y % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            x = app.DisplacementEditField.Value;
            v = app.VelocityEditField.Value;
            m = app.MassSlider.Value;
            c = app.DampnessSlider.Value;
            k = app.StifnessKnob.Value;
            app.t = x/v;
            T = app.t;
            if k ~= 0
                if m ~= 0
                    app.DampnessGauge.Limits = [0 8*(k*m)];
                    app.DampnessGauge.MajorTicks = [0 4*(k*m) 8*(k*m)];
                    app.DampnessGauge.ScaleColorLimits = [7.2*(k*m) 8*(k*m);5.3*(k*m) 7.2*(k*m);2.6*(k*m) 5.3*(k*m);0 0.8*(k*m);0.8*(k*m) 2.6*(k*m)];
                    app.DampnessGauge.Value = c^2;
                end
            end
            cd = 2*((k*m)^0.5);
            dr = c/cd;
            w = (k/m)^0.5;
            A = c/(2*m);
            W = (w^2 - A^2)^0.5;
            B = (A^2 - w^2)^0.5;
            f = (1/(2*3.14))*w;
            if v ~= 0
                app.t = 0:0.01:app.t;
            end
            if c == 0
                app.y = x*cos(w*app.t) + (v/w)*sin(w*app.t); 
            elseif c^2 < 4*(k*m)
                app.y = x.*exp(-A.*app.t).*cos(w.*app.t) + ((v+A.*x)/W).*exp(-A.*app.t).*sin(W.*app.t);
            elseif c^2 == 4*(k*m)
                app.y = x.*exp(-A.*app.t) + (v+A.*x).*app.t.*exp(-A.*app.t);
            elseif c^2 > 4*(k*m)
                app.y = ((v+(A+B).*x)/2.*B).*exp(-(A-B).*app.t) + ((v+(A-B).*x)/2.*B).*exp(-(A+B).*app.t);
            end
            labels = ["angular frequency" "period of vibration" "natural frequency" "damping ratio" "critical damping" "frequency of damped oscillation"];
            values = {f T w dr cd W};
            app.UITable.Data = [labels.' values.'];
        end

        % Value changed function: DisplacementEditField
        function DisplacementEditFieldValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: MassSlider
        function MassSliderValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: DampnessSlider
        function DampnessSliderValueChanged(app, event)
            startupFcn(app)
        end

        % Value changed function: StifnessKnob
        function StifnessKnobValueChanged(app, event)
            startupFcn(app)
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if string(app.TypeKnob.Value) == "Solid" 
                plot(app.UIAxes,app.t,app.y)
            elseif string(app.TypeKnob.Value) == "Dot" 
                plot(app.UIAxes,app.t,app.y,':')
            elseif string(app.TypeKnob.Value) == "Dotted Dash" 
                plot(app.UIAxes,app.t,app.y,'-.')
            elseif string(app.TypeKnob.Value) == "Dashed" 
                plot(app.UIAxes,app.t,app.y,'--')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisplacementEditFieldLabel = uilabel(app.UIFigure);
            app.DisplacementEditFieldLabel.Text = 'Displacement';
            app.DisplacementEditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplacementEditField.ValueChangedFcn = createCallbackFcn(app, @DisplacementEditFieldValueChanged, true);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.MassSliderLabel = uilabel(app.UIFigure);
            app.MassSliderLabel.Text = 'Mass';
            app.MassSlider = uislider(app.UIFigure);
            app.MassSlider.ValueChangedFcn = createCallbackFcn(app, @MassSliderValueChanged, true);
            app.DampnessSliderLabel = uilabel(app.UIFigure);
            app.DampnessSliderLabel.Text = 'Dampness';
            app.DampnessSlider = uislider(app.UIFigure);
            app.DampnessSlider.ValueChangedFcn = createCallbackFcn(app, @DampnessSliderValueChanged, true);
            app.StifnessKnobLabel = uilabel(app.UIFigure);
            app.StifnessKnobLabel.Text = 'Stifness';
            app.StifnessKnob = uiknob(app.UIFigure, 'continuous');
            app.StifnessKnob.ValueChangedFcn = createCallbackFcn(app, @StifnessKnobValueChanged, true);
            app.DampnessGaugeLabel = uilabel(app.UIFigure);
            app.DampnessGaugeLabel.Text = 'Dampness';
            app.DampnessGauge = uigauge(app.UIFigure, 'semicircular');
            app.DampnessGauge.Limits = [0 30000];
            app.DampnessGauge.MajorTicks = [0 15000 30000];
            app.DampnessGauge.MajorTickLabels = {'Undamped', 'Critical', 'Over damped'};
            app.TypeKnobLabel = uilabel(app.UIFigure);
            app.TypeKnobLabel.Text = 'Type';
            app.TypeKnob = uiknob(app.UIFigure, 'discrete');
            app.TypeKnob.Items = {'Solid', 'Dot', 'Dotted Dash', 'Dashed'};
            app.TypeKnob.Value = 'Solid';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end